classdef CVE_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MainTab                     matlab.ui.container.Tab
        axes1                       matlab.ui.control.UIAxes
        inputVideoPanel             matlab.ui.container.Panel
        selectedVideodir            matlab.ui.control.Label
        selectedVideo               matlab.ui.control.Label
        removeVideoButton           matlab.ui.control.Button
        SelectedVideoList           matlab.ui.control.ListBox
        startframeEditFieldLabel    matlab.ui.control.Label
        startframeEditField         matlab.ui.control.NumericEditField
        endframeEditFieldLabel      matlab.ui.control.Label
        endframeEditField           matlab.ui.control.NumericEditField
        framerateText               matlab.ui.control.Label
        framerateEdit               matlab.ui.control.NumericEditField
        controlPanel                matlab.ui.container.Panel
        loadSettings                matlab.ui.control.Button
        startProcessing             matlab.ui.control.Button
        saveSettings                matlab.ui.control.Button
        analysisStepsPanel          matlab.ui.container.Panel
        imageAnalysisCheckBox       matlab.ui.control.CheckBox
        imageProcessingCheckBox     matlab.ui.control.CheckBox
        selectNewROIs               matlab.ui.control.CheckBox
        ImageprocessingTab          matlab.ui.container.Tab
        processingPanel             matlab.ui.container.Panel
        IMFILL                      matlab.ui.control.CheckBox
        binarization                matlab.ui.control.CheckBox
        grayscaleInversion          matlab.ui.control.CheckBox
        backgroundRemoval           matlab.ui.control.CheckBox
        visualControl               matlab.ui.control.CheckBox
        outputVideo                 matlab.ui.control.CheckBox
        NF                          matlab.ui.control.CheckBox
        roiSelection                matlab.ui.control.CheckBox
        LIF                         matlab.ui.control.CheckBox
        Realignment                 matlab.ui.control.CheckBox
        Rotation                    matlab.ui.control.CheckBox
        synchronization             matlab.ui.control.CheckBox
        backgroundPanel             matlab.ui.container.Panel
        runAvgText2                 matlab.ui.control.Label
        runAvgText                  matlab.ui.control.Label
        averageText2                matlab.ui.control.Label
        averageText                 matlab.ui.control.Label
        averageEdit                 matlab.ui.control.NumericEditField
        runAvgEdit                  matlab.ui.control.NumericEditField
        backgroundPopUp             matlab.ui.control.DropDown
        NFPanel                     matlab.ui.container.Panel
        text4                       matlab.ui.control.Label
        filteringElementPopUp       matlab.ui.control.DropDown
        filteringElement            matlab.ui.control.Label
        reconstruction              matlab.ui.control.CheckBox
        NFelementRadiusEdit         matlab.ui.control.NumericEditField
        binarizationPanel           matlab.ui.container.Panel
        binarizationTHtext          matlab.ui.control.Label
        binarizationPopUp           matlab.ui.control.DropDown
        binarizationTHedit          matlab.ui.control.NumericEditField
        ImageAnalysisTab            matlab.ui.container.Tab
        Analysissteps               matlab.ui.container.Panel
        haematocritCheckBox         matlab.ui.control.CheckBox
        PTVCheckBox                 matlab.ui.control.CheckBox
        haematocritVsPTVCheckBox    matlab.ui.control.CheckBox
        generalAnalysisCheckBox     matlab.ui.control.CheckBox
        visualPTVControlCheckBox    matlab.ui.control.CheckBox
        Heamatocritcalcoption       matlab.ui.container.Panel
        saveFiguresHaemCheckBox     matlab.ui.control.CheckBox
        saveMatHaemCheckBox         matlab.ui.control.CheckBox
        text17                      matlab.ui.control.Label
        text16                      matlab.ui.control.Label
        text15                      matlab.ui.control.Label
        domainWidthCheckBox         matlab.ui.control.CheckBox
        feedHaemEdit                matlab.ui.control.NumericEditField
        channelHeightEdit           matlab.ui.control.NumericEditField
        channelWidthEdit            matlab.ui.control.NumericEditField
        TipeofBloodDropDownLabel    matlab.ui.control.Label
        TipeofBloodDropDown         matlab.ui.control.DropDown
        DiametrerdefinitonDropDownLabel  matlab.ui.control.Label
        DiametrerdefinitonDropDown  matlab.ui.control.DropDown
        ptvPanel                    matlab.ui.container.Panel
        PTVsettings                 matlab.ui.container.Panel
        CrosscorrelationparametersButtonGroup  matlab.ui.container.ButtonGroup
        text23                      matlab.ui.control.Label
        text24                      matlab.ui.control.Label
        text26                      matlab.ui.control.Label
        minCorrelationText          matlab.ui.control.Label
        nrParticlesEdit             matlab.ui.control.NumericEditField
        interrogationAreaEdit       matlab.ui.control.NumericEditField
        minCorrelationEdit          matlab.ui.control.NumericEditField
        similarityNeighEdit         matlab.ui.control.NumericEditField
        MethodDropDownLabel         matlab.ui.control.Label
        MethodDropDown              matlab.ui.control.DropDown
        PTValgorithmLabel           matlab.ui.control.Label
        PTValgorithmDropDown        matlab.ui.control.DropDown
        particleDetectionPanel      matlab.ui.container.Panel
        text18                      matlab.ui.control.Label
        text19                      matlab.ui.control.Label
        text20                      matlab.ui.control.Label
        setSigmaAutoCheckBox        matlab.ui.control.CheckBox
        corrThresholdEdit           matlab.ui.control.NumericEditField
        intensityThresholdEdit      matlab.ui.control.NumericEditField
        sigmaEdit                   matlab.ui.control.NumericEditField
        ParticleDetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        ParticleDetectionAlgorithmDropDown  matlab.ui.control.DropDown
        abalysisdomainotions        matlab.ui.container.Panel
        nDomainsText                matlab.ui.control.Label
        calibrationText             matlab.ui.control.Label
        GAdomainsText               matlab.ui.control.Label
        nDomainsEdit                matlab.ui.control.NumericEditField
        calibrationEdit             matlab.ui.control.NumericEditField
        GAdomainsEdit               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
          %Specific UserData
          ROI  
          GAdomains
          GAvertices
          domainROI
          domainMask 
          LIF_ROI
          angle_rotation
       
    end
    
    methods (Access = private)
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
        
        function EndFramedunc(app)
            
            endFrameSet  = app.endframeEditField.Value;
            inputVideoPath = evalin('base','inputVideoPath'); %Read in variables from workspace(base)
            inputVideoNames = evalin('base','inputVideoNames');
            infovideo = imfinfo([inputVideoPath inputVideoNames{1}]);
            maxEndFrame = size(infovideo,1);
            %maxEndFrame = infovideo.NumFrames;
            startFrame = app.startframeEditField.Value;
            if(endFrameSet <= startFrame)
                app.endframeEditField.Value=startFrame+1;
            end
            if(endFrameSet > maxEndFrame)
                app.endframeEditField.Value=maxEndFrame;
            end
            if(endFrameSet <= 1)
                app.endframeEditField.Value=2;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CVE_GUI_test_OpeningFcn(app, varargin)
         
%             
            try
                %Display name of selected video in static text field:
                inputVideoPath = evalin('base','inputVideoPath'); %Read in variables from workspace(base)
                inputVideoNames = evalin('base','inputVideoNames');
                %Display the selected videos
                app.selectedVideodir.Text = inputVideoPath;
                for i=1:length(inputVideoNames)
                    stringList{i} = inputVideoNames{i};
                end
                app.SelectedVideoList.Items = stringList;
                %Plot first frame of the video:
                video = [inputVideoPath inputVideoNames{1}];
                image = im2uint8(imread(video,1));
                imshow(image,[])
            end
            
            try %Fails if no image is loaded
                %Initialize following settings:
                %Set ROI (for image processing) to be the full image:
                app.ROI = [0 0 size(image,2) size(image,1)];
                %Set domainROI (for image analysis) to be the full image:
                app.domainROI= [0 0 size(image,2) size(image,1)];
                app.domainMask = ones(size(image));
                app.LIF_ROI= [];
                app.angle_rotation = [];
               % app.GAdomains=;
%                % app.GAvertices=;
            catch
                disp(' No lodaed')
            end
            
            EndFramedunc(app)


        end

        % Callback function
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.figure1, char(state));
        end

        % Callback function
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Value changed function: startframeEditField
        function startframeEditFieldValueChanged(app, event)
            inputVideoPath = evalin('base','inputVideoPath'); %Read in variables from workspace(base)
            inputVideoNames = evalin('base','inputVideoNames');
            startFrame  = app.startframeEditField.Value;
            endFrame = app.endframeEditField.Value;
            infovideo = VideoReader([inputVideoPath inputVideoNames{1}]);
            maxEndFrame = infovideo.NumFrames;
            
            if(startFrame>=endFrame)
                app.startframeEditField.Value=endFrame-1;
            end
            if(startFrame<1)
                app.startframeEditField.Value=1;
            end
            if(startFrame>=maxEndFrame)
                app.startframeEditField.Value=endFrame-1;
            end
            
        end

        % Value changed function: endframeEditField
        function endframeEditFieldValueChanged(app, event)

            EndFramedunc(app)
            
        end

        % Value changed function: averageEdit
        function averageEditValueChanged(app, event)
            value = app.averageEdit.Value;
              integerValue = round(value);
            if(integerValue <=0)
                integerValue = 1;
                
            end
            app.averageEdit.Value=integerValue ;
        end

        % Value changed function: runAvgEdit
        function runAvgEditValueChanged(app, event)
            value = app.runAvgEdit.Value;
              integerValue = round(value);
            if(integerValue <=0)
                integerValue = 1;
            end
            app.runAvgEdit.Value=integerValue ;
        end

        % Value changed function: NFelementRadiusEdit
        function NFelementRadiusEditValueChanged(app, event)
            value = app.NFelementRadiusEdit.Value;
             integerValue = round(value);
            if(integerValue <=0)
                integerValue = 1;
            end
            app.NFelementRadiusEdit.Value=integerValue ;
        end

        % Value changed function: binarizationTHedit
        function binarizationTHeditValueChanged(app, event)
            value = app.binarizationTHedit.Value;
            integerValue = value;
            if(integerValue < 0)
                integerValue = 0;
            end
            if(integerValue > 255)
                integerValue = 255;
            end
            app.binarizationTHedit.Value=integerValue ;
        end

        % Value changed function: framerateEdit
        function framerateEditValueChanged(app, event)
            value = app.framerateEdit.Value;
              integerValue = round(value);
            if(integerValue <=0)
                integerValue = 1;
            end
            app.framerateEdit.Value=integerValue ;
        end

        % Value changed function: corrThresholdEdit
        function corrThresholdEditValueChanged(app, event)
            value = app.corrThresholdEdit.Value;
             integerValue = value;
            if(integerValue < 0)
                integerValue = 0;
            end
            if(integerValue > 1.0)
                integerValue = 1.0;
            end
            app.corrThresholdEdit.Value=integerValue ;
        end

        % Value changed function: intensityThresholdEdit
        function intensityThresholdEditValueChanged(app, event)
            value = app.intensityThresholdEdit.Value;
              integerValue = value;
            if(integerValue < 0)
                integerValue = 0;
            end
            if(integerValue > 255)
                integerValue = 255;
            end
            app.intensityThresholdEdit.Value=integerValue ;
        end

        % Value changed function: sigmaEdit
        function sigmaEditValueChanged(app, event)
            value = app.sigmaEdit.Value;
                  integerValue = round(value);
            if(integerValue <=0)
                integerValue = 1;
            end
            app.sigmaEdit.Value=integerValue ;
        end

        % Value changed function: nrParticlesEdit
        function nrParticlesEditValueChanged(app, event)
            value = app.nrParticlesEdit.Value;
              integerValue = round(value);
            if(integerValue <0)
                integerValue = 0;
            end
            app.nrParticlesEdit.Value=integerValue;
        end

        % Value changed function: interrogationAreaEdit
        function interrogationAreaEditValueChanged(app, event)
            value = app.interrogationAreaEdit.Value;
              integerValue = round(value);
            if(integerValue <0)
                integerValue = 0;
            end
            app.interrogationAreaEdit.Value=integerValue;
        end

        % Value changed function: minCorrelationEdit
        function minCorrelationEditValueChanged(app, event)
            value = app.minCorrelationEdit.Value;
              inValue = value;
            if(inValue < 0)
                inValue = 0;
            end
            if(inValue > 1.0)
                inValue = 1.0;
            end
            app.minCorrelationEdit.Value=inValue;
        end

        % Value changed function: similarityNeighEdit
        function similarityNeighEditValueChanged(app, event)
            value = app.similarityNeighEdit.Value;
            integerValue = value;
            if(integerValue < 0)
                integerValue = 0;
            end
            if(integerValue > 100)
                integerValue = 100;
            end
            app.similarityNeighEdit.Value=integerValue;
        end

        % Value changed function: domainWidthCheckBox
        function domainWidthCheckBoxValueChanged(app, event)
            value = app.domainWidthCheckBox.Value;
             useDomainWidth = value;
            if(useDomainWidth)
                app.channelWidthEdit.Enable = 'off';
            else
                app.channelWidthEdit.Enable = 'on';
            end
        end

        % Button pushed function: saveSettings
        function saveSettingsButtonPushed(app, event)
            
             inputVideoPath = app.selectedVideodir.Text;
            fieldElements = fieldnames(app);
            for i=1:numel(fieldElements)
                fieldElement = fieldElements{i};
                a{i,1}= class(app.(fieldElement));
           
                    
                if (strcmp(a(i,1),'matlab.ui.control.ListBox'))
                     UserSettingsGUIstring.(fieldElement) = app.(fieldElement).Items;
                end

                if (strcmp(a(i,1),'matlab.ui.control.NumericEditField'))
                    UserSettingsGUIvalue.(fieldElement) = app.(fieldElement).Value;
                end
                if (strcmp(a(i,1),'matlab.ui.control.DropDown'))
                    UserSettingsGUIvalue.(fieldElement) = app.(fieldElement).Value;
                end

                if(strcmp(a(i,1),'matlab.ui.control.CheckBox'))
                    UserSettingsGUIvalue.(fieldElement) = app.(fieldElement).Value;
                end
                if strcmpi(fieldElement,'selectedVideodir')
                    UserSettingsGUIvalue.(fieldElement) = app.(fieldElement).Text;
                end
            
             end
          
            %Specific UserData
            o1=app.domainROI;
            o2=app.domainMask;
            o3=app.GAdomains;
            o4=app.GAvertices;
            o5=app.ROI;     
            o6=app.LIF_ROI;
            o7=app.angle_rotation;
            UserSettingsGUIvalue.domainROI = o1;
            UserSettingsGUIvalue.domainMask = o2;
            UserSettingsGUIvalue.GAdomains = o3;
            UserSettingsGUIvalue.GAvertices = o4;
            UserSettingsGUIvalue.ROI = o5;
            UserSettingsGUIvalue.LIF_ROI =o6 ;
            UserSettingsGUIvalue.angle_rotation =o7;
                
            save([inputVideoPath 'UserSettingsGUI.mat'],'UserSettingsGUIvalue','UserSettingsGUIstring')
            disp(' User Settings saved in UserSettingsGUI.mat')
            
           
        end

        % Value changed function: setSigmaAutoCheckBox
        function setSigmaAutoCheckBoxValueChanged(app, event)
            value = app.setSigmaAutoCheckBox.Value;
           auto=value;
            if(auto)
                app.sigmaEdit.Enable ='off';
            else
                app.sigmaEdit.Enable ='on';
            end
        end

        % Button pushed function: removeVideoButton
        function removeVideoButtonPushed(app, event)
                         
            [~,idx] = ismember({num2str(app.SelectedVideoList.Value)},app.SelectedVideoList.Items);
            app.SelectedVideoList.Items(idx) = [];
        end

        % Button pushed function: loadSettings
        function loadSettingsButtonPushed(app, event)
            
            
            inputVideoPath = app.selectedVideodir.Text;
            %load settings from UserSettingsGUI.mat into current session
            try
                load([inputVideoPath 'UserSettingsGUI.mat'])
                fieldElementsvalue = fieldnames(UserSettingsGUIvalue);
                for i=1:numel(fieldElementsvalue)
                    fieldElement = fieldElementsvalue{i};
                    switch fieldElement
                        case {'SelectedVideoList','selectedVideodir'}
                            %These are no processing settings. They contain the video Path and
                            %Names which should not be loaded.
                        case {'domainMask','domainROI','GAdomains','GAvertices','ROI','LIF_ROI','angle_rotation'}
                            app.(fieldElement) = UserSettingsGUIvalue.(fieldElement);                            
                        otherwise
                            app.(fieldElement).Value = UserSettingsGUIvalue.(fieldElement);      
                    end
                end
                fieldElementsstring = fieldnames(UserSettingsGUIstring);
                for i=1:numel(fieldElementsstring)
                    fieldElement = fieldElementsstring{i};
                    switch fieldElement
                        case {'SelectedVideoList','selectedVideodir'}
                           %These are no processing settings. They contain the video Path and
                            %Names which should not be loaded. 
                        otherwise
                        app.(fieldElement).Items = UserSettingsGUIstring.(fieldElement);
                    end
                end      
                disp('Loaded User Settings for the GUI from UserSettingsGUI.mat')
      
                %Try to plot ROIs
                try
                    inputVideoNames = evalin('base','inputVideoNames');
                    startFrame = app.startframeEditField.Value;
                    video = [inputVideoPath inputVideoNames{1}];
                    image = im2uint8(imread(video,startFrame));
                    imshow(image,[]) %Frame without ROI
                    image = plotROIs(image,UserSettingsGUIvalue);
                   imshow(image,[]) %Frame with loaded ROIs
                catch
                    disp('No saved ROI settings')
                end
            catch
                disp(['No saved UserSettings.mat available in: ' inputVideoPath])
            end
        end

        % Button pushed function: startProcessing
        function startProcessingButtonPushed(app, event)
            inputVideoPath = app.selectedVideodir.Text;
            inputVideoNames = app.SelectedVideoList.Items;     
            fieldElements = fieldnames(app);
            for i=1:numel(fieldElements)
                fieldElement = fieldElements{i};
                a{i,1}= class(app.(fieldElement));
               
                    if (strcmp(a(i,1),'matlab.ui.control.ListBox'))
                     UserSettingsGUIstring.(fieldElement) = app.(fieldElement).Items;
                    end

                    if (strcmp(a(i,1),'matlab.ui.control.NumericEditField'))
                    UserSettingsGUIvalue.(fieldElement) = app.(fieldElement).Value;
                    end
                    if (strcmp(a(i,1),'matlab.ui.control.DropDown'))
                    UserSettingsGUIvalue.(fieldElement) = app.(fieldElement).Value;
                    end
                
                    if(strcmp(a(i,1),'matlab.ui.control.CheckBox'))
                    UserSettingsGUIvalue.(fieldElement) = app.(fieldElement).Value;
                    end
                    if strcmpi(fieldElement,'selectedVideodir')
                    UserSettingsGUIvalue.(fieldElement) = app.(fieldElement).Text;
                    end
               
            end
            
            
            o1=app.domainROI;
            o2=app.domainMask;
            o3=app.GAdomains;
            o4=app.GAvertices;
            o5=app.ROI;     
            o6=app.LIF_ROI;
            o7=app.angle_rotation;
            UserSettingsGUIvalue.domainROI = o1;
            UserSettingsGUIvalue.domainMask = o2;
            UserSettingsGUIvalue.GAdomains = o3;
            UserSettingsGUIvalue.GAvertices = o4;
            UserSettingsGUIvalue.ROI = o5;
            UserSettingsGUIvalue.LIF_ROI =o6 ;
            UserSettingsGUIvalue.angle_rotation =o7;


            save('.\Temporary\UserSettingsGUItemp.mat','UserSettingsGUIvalue','UserSettingsGUIstring','inputVideoNames')
            save([inputVideoPath 'UserSettingsGUI.mat'],'UserSettingsGUIvalue','UserSettingsGUIstring')
            
            %Check Number of domains matches
            if(app.selectNewROIs.Value==0 && app.imageAnalysisCheckBox.Value==1)
                display = false;
                string1 = '';
                string2 = '';
                if(app.nDomainsEdit.Value ~= size(app.domainROI,1))
                    string1 = 'Mismatch between nr. of domains and already selected domains';
                    display = true;
                end
                if(app.GAdomainsEdit.Value ~= size(app.GAvertices,2))
                    string2 = 'Mismatch between nr. additional domains and already selected domains';
                    display = true;
                end
                if(display == true)
                    answer = questdlg({string1,string2,'Do you want to continue?'},'Warning','Yes','No','No');
                    if(strcmp(answer,'Yes'))
                        %Close GUI and proceed with Analysis
                        close(gcf)
                    else
                        %Do nothing, wait for reselection
                    end
                else
                    %Close GUI and proceed with Analysis
                    close(gcf)
                end
            else
                %Close GUI and proceed with Analysis
                close(gcf)
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            
            if strcmp((app.MethodDropDown.Value),'By interrogation area')
                app.nrParticlesEdit.Enable = 'off';
                 app.interrogationAreaEdit.Enable = 'on';
                 app.minCorrelationEdit.Enable = 'on';
                 app.similarityNeighEdit.Enable = 'on';
                
            else
                  app.nrParticlesEdit.Enable = 'on';
                 app.interrogationAreaEdit.Enable = 'off';
                 app.minCorrelationEdit.Enable = 'off';
                 app.similarityNeighEdit.Enable = 'off';
            end
        end

        % Value changed function: SelectedVideoList
        function SelectedVideoListValueChanged(app, event)
            value = app.SelectedVideoList.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CVE_GUI_test';
            app.TabGroup = uitabgroup(app.figure1);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.axes1 = uiaxes(app.MainTab);
            app.inputVideoPanel = uipanel(app.MainTab);
            app.inputVideoPanel.Title = 'INPUT VIDEO OPTIONS';
            app.selectedVideodir = uilabel(app.inputVideoPanel);
            app.selectedVideodir.Text = 'none';
            app.selectedVideo = uilabel(app.inputVideoPanel);
            app.selectedVideo.Text = 'Selected video:';
            app.removeVideoButton = uibutton(app.inputVideoPanel, 'push');
            app.removeVideoButton.ButtonPushedFcn = createCallbackFcn(app, @removeVideoButtonPushed, true);
            app.removeVideoButton.Text = 'Remove Video';
            app.SelectedVideoList = uilistbox(app.inputVideoPanel);
            app.SelectedVideoList.ValueChangedFcn = createCallbackFcn(app, @SelectedVideoListValueChanged, true);
            app.startframeEditFieldLabel = uilabel(app.inputVideoPanel);
            app.startframeEditFieldLabel.Text = 'startframe :';
            app.startframeEditField = uieditfield(app.inputVideoPanel, 'numeric');
            app.startframeEditField.ValueChangedFcn = createCallbackFcn(app, @startframeEditFieldValueChanged, true);
            app.startframeEditField.Value = 1;
            app.endframeEditFieldLabel = uilabel(app.inputVideoPanel);
            app.endframeEditFieldLabel.Text = 'endframe :';
            app.endframeEditField = uieditfield(app.inputVideoPanel, 'numeric');
            app.endframeEditField.ValueChangedFcn = createCallbackFcn(app, @endframeEditFieldValueChanged, true);
            app.endframeEditField.Value = 100000;
            app.framerateText = uilabel(app.inputVideoPanel);
            app.framerateText.Text = 'framerate [fps] :';
            app.framerateEdit = uieditfield(app.inputVideoPanel, 'numeric');
            app.framerateEdit.ValueChangedFcn = createCallbackFcn(app, @framerateEditValueChanged, true);
            app.framerateEdit.Value = 395;
            app.controlPanel = uipanel(app.MainTab);
            app.loadSettings = uibutton(app.controlPanel, 'push');
            app.loadSettings.ButtonPushedFcn = createCallbackFcn(app, @loadSettingsButtonPushed, true);
            app.loadSettings.Text = 'Load User Settings for GUI';
            app.startProcessing = uibutton(app.controlPanel, 'push');
            app.startProcessing.ButtonPushedFcn = createCallbackFcn(app, @startProcessingButtonPushed, true);
            app.startProcessing.Text = 'START';
            app.saveSettings = uibutton(app.controlPanel, 'push');
            app.saveSettings.ButtonPushedFcn = createCallbackFcn(app, @saveSettingsButtonPushed, true);
            app.saveSettings.Text = 'Save these User Settings';
            app.analysisStepsPanel = uipanel(app.MainTab);
            app.analysisStepsPanel.Title = 'Analysis Steps';
            app.imageAnalysisCheckBox = uicheckbox(app.analysisStepsPanel);
            app.imageAnalysisCheckBox.Text = 'Image analysis';
            app.imageAnalysisCheckBox.Value = true;
            app.imageProcessingCheckBox = uicheckbox(app.analysisStepsPanel);
            app.imageProcessingCheckBox.Text = 'Image processing';
            app.imageProcessingCheckBox.Value = true;
            app.selectNewROIs = uicheckbox(app.analysisStepsPanel);
            app.selectNewROIs.Text = 'Select new Domians and general ROI';
            app.selectNewROIs.Value = true;
            app.ImageprocessingTab = uitab(app.TabGroup);
            app.ImageprocessingTab.Title = 'Image processing';
            app.processingPanel = uipanel(app.ImageprocessingTab);
            app.processingPanel.Title = 'IMAGE PROCESSING STEPS';
            app.IMFILL = uicheckbox(app.processingPanel);
            app.IMFILL.Text = 'RBC filling';
            app.binarization = uicheckbox(app.processingPanel);
            app.binarization.Text = 'Binarization';
            app.grayscaleInversion = uicheckbox(app.processingPanel);
            app.grayscaleInversion.Text = 'Grayscale inversion';
            app.backgroundRemoval = uicheckbox(app.processingPanel);
            app.backgroundRemoval.Text = 'Background removal';
            app.visualControl = uicheckbox(app.processingPanel);
            app.visualControl.Text = 'Visual control check';
            app.outputVideo = uicheckbox(app.processingPanel);
            app.outputVideo.Text = 'Create video';
            app.NF = uicheckbox(app.processingPanel);
            app.NF.Text = 'Noise filtering (NF)';
            app.roiSelection = uicheckbox(app.processingPanel);
            app.roiSelection.Text = 'new general ROI selection';
            app.LIF = uicheckbox(app.processingPanel);
            app.LIF.Text = 'Light Intensity filtering (LIF)';
            app.Realignment = uicheckbox(app.processingPanel);
            app.Realignment.Text = 'Realignment';
            app.Rotation = uicheckbox(app.processingPanel);
            app.Rotation.Text = 'Rotation';
            app.synchronization = uicheckbox(app.processingPanel);
            app.synchronization.Text = 'Synchronization';
            app.backgroundPanel = uipanel(app.ImageprocessingTab);
            app.backgroundPanel.Title = 'BACKGROUND REMOVAL OPTIONS';
            app.runAvgText2 = uilabel(app.backgroundPanel);
            app.runAvgText2.Text = 'average over i frames:  i =';
            app.runAvgText = uilabel(app.backgroundPanel);
            app.runAvgText.Text = 'Running average:';
            app.averageText2 = uilabel(app.backgroundPanel);
            app.averageText2.Text = 'average every n frames:  n =';
            app.averageText = uilabel(app.backgroundPanel);
            app.averageText.Text = 'Average:';
            app.averageEdit = uieditfield(app.backgroundPanel, 'numeric');
            app.averageEdit.ValueChangedFcn = createCallbackFcn(app, @averageEditValueChanged, true);
            app.averageEdit.Value = 1;
            app.runAvgEdit = uieditfield(app.backgroundPanel, 'numeric');
            app.runAvgEdit.ValueChangedFcn = createCallbackFcn(app, @runAvgEditValueChanged, true);
            app.runAvgEdit.Value = 50;
            app.backgroundPopUp = uidropdown(app.backgroundPanel);
            app.backgroundPopUp.Items = {'first frame', 'minimum', 'average', 'running average', 'gaussian mixture model'};
            app.backgroundPopUp.Value = 'running average';
            app.NFPanel = uipanel(app.ImageprocessingTab);
            app.NFPanel.Title = 'NOISE FILTER OPTIONS';
            app.text4 = uilabel(app.NFPanel);
            app.text4.Text = 'element Radius [px] =';
            app.filteringElementPopUp = uidropdown(app.NFPanel);
            app.filteringElementPopUp.Items = {'disk'};
            app.filteringElementPopUp.Value = 'disk';
            app.filteringElement = uilabel(app.NFPanel);
            app.filteringElement.Text = 'Filtering Element:';
            app.reconstruction = uicheckbox(app.NFPanel);
            app.reconstruction.Text = 'opening by reconstruction';
            app.reconstruction.Value = true;
            app.NFelementRadiusEdit = uieditfield(app.NFPanel, 'numeric');
            app.NFelementRadiusEdit.ValueChangedFcn = createCallbackFcn(app, @NFelementRadiusEditValueChanged, true);
            app.NFelementRadiusEdit.Value = 1;
            app.binarizationPanel = uipanel(app.ImageprocessingTab);
            app.binarizationPanel.Title = 'BINARIZATION OPTIONS';
            app.binarizationTHtext = uilabel(app.binarizationPanel);
            app.binarizationTHtext.Text = 'manual binarization threshold =';
            app.binarizationPopUp = uidropdown(app.binarizationPanel);
            app.binarizationPopUp.Items = {'manual', 'automatic'};
            app.binarizationPopUp.Value = 'manual';
            app.binarizationTHedit = uieditfield(app.binarizationPanel, 'numeric');
            app.binarizationTHedit.ValueChangedFcn = createCallbackFcn(app, @binarizationTHeditValueChanged, true);
            app.binarizationTHedit.Value = 12;
            app.ImageAnalysisTab = uitab(app.TabGroup);
            app.ImageAnalysisTab.Title = 'Image Analysis';
            app.Analysissteps = uipanel(app.ImageAnalysisTab);
            app.Analysissteps.Title = 'ANALYSIS STEPS';
            app.haematocritCheckBox = uicheckbox(app.Analysissteps);
            app.haematocritCheckBox.Text = 'Heamatocrit calc.';
            app.PTVCheckBox = uicheckbox(app.Analysissteps);
            app.PTVCheckBox.Text = 'PTV';
            app.haematocritVsPTVCheckBox = uicheckbox(app.Analysissteps);
            app.haematocritVsPTVCheckBox.Text = 'Haematocrit vs PTV';
            app.generalAnalysisCheckBox = uicheckbox(app.Analysissteps);
            app.generalAnalysisCheckBox.Text = 'General Analysis (struct f)';
            app.visualPTVControlCheckBox = uicheckbox(app.Analysissteps);
            app.visualPTVControlCheckBox.Text = 'Visual PTV control';
            app.Heamatocritcalcoption = uipanel(app.ImageAnalysisTab);
            app.Heamatocritcalcoption.Title = 'HAEMATOCRIT CALC. OPTIONS';
            app.saveFiguresHaemCheckBox = uicheckbox(app.Heamatocritcalcoption);
            app.saveFiguresHaemCheckBox.Text = 'Save figures';
            app.saveMatHaemCheckBox = uicheckbox(app.Heamatocritcalcoption);
            app.saveMatHaemCheckBox.Text = 'Save results to matrix';
            app.saveMatHaemCheckBox.Value = true;
            app.text17 = uilabel(app.Heamatocritcalcoption);
            app.text17.Text = 'Channel height [µm] :';
            app.text16 = uilabel(app.Heamatocritcalcoption);
            app.text16.Text = 'Channel width [µm] :';
            app.text15 = uilabel(app.Heamatocritcalcoption);
            app.text15.Text = 'Feed haematocrit [%] :';
            app.domainWidthCheckBox = uicheckbox(app.Heamatocritcalcoption);
            app.domainWidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @domainWidthCheckBoxValueChanged, true);
            app.domainWidthCheckBox.Text = 'Use domain width';
            app.feedHaemEdit = uieditfield(app.Heamatocritcalcoption, 'numeric');
            app.feedHaemEdit.Value = 10;
            app.channelHeightEdit = uieditfield(app.Heamatocritcalcoption, 'numeric');
            app.channelHeightEdit.Value = 8;
            app.channelWidthEdit = uieditfield(app.Heamatocritcalcoption, 'numeric');
            app.channelWidthEdit.Value = 10;
            app.TipeofBloodDropDownLabel = uilabel(app.Heamatocritcalcoption);
            app.TipeofBloodDropDownLabel.Text = 'Tipe of Blood';
            app.TipeofBloodDropDown = uidropdown(app.Heamatocritcalcoption);
            app.TipeofBloodDropDown.Items = {'Human', 'Pig (Namdee)', 'Pig (Salvioli)', ''};
            app.TipeofBloodDropDown.Value = 'Pig (Salvioli)';
            app.DiametrerdefinitonDropDownLabel = uilabel(app.Heamatocritcalcoption);
            app.DiametrerdefinitonDropDownLabel.Text = 'Diametrer definiton';
            app.DiametrerdefinitonDropDown = uidropdown(app.Heamatocritcalcoption);
            app.DiametrerdefinitonDropDown.Items = {'Hidraulic', 'Based on equality', ''};
            app.DiametrerdefinitonDropDown.Value = 'Hidraulic';
            app.ptvPanel = uipanel(app.ImageAnalysisTab);
            app.ptvPanel.Title = 'PTV OPTIONS';
            app.PTVsettings = uipanel(app.ptvPanel);
            app.PTVsettings.Title = 'PTV settings';
            app.CrosscorrelationparametersButtonGroup = uibuttongroup(app.PTVsettings);
            app.CrosscorrelationparametersButtonGroup.Title = 'Cross-correlation parameters';
            app.text23 = uilabel(app.CrosscorrelationparametersButtonGroup);
            app.text23.Text = 'Nr. of particles';
            app.text24 = uilabel(app.CrosscorrelationparametersButtonGroup);
            app.text24.Text = 'Interrogation area [px]';
            app.text26 = uilabel(app.CrosscorrelationparametersButtonGroup);
            app.text26.Text = 'Similarity neighbors [%]';
            app.minCorrelationText = uilabel(app.CrosscorrelationparametersButtonGroup);
            app.minCorrelationText.Text = 'Minimum correlation';
            app.nrParticlesEdit = uieditfield(app.CrosscorrelationparametersButtonGroup, 'numeric');
            app.nrParticlesEdit.ValueChangedFcn = createCallbackFcn(app, @nrParticlesEditValueChanged, true);
            app.nrParticlesEdit.Enable = 'off';
            app.nrParticlesEdit.Value = 10;
            app.interrogationAreaEdit = uieditfield(app.CrosscorrelationparametersButtonGroup, 'numeric');
            app.interrogationAreaEdit.ValueChangedFcn = createCallbackFcn(app, @interrogationAreaEditValueChanged, true);
            app.interrogationAreaEdit.Value = 20;
            app.minCorrelationEdit = uieditfield(app.CrosscorrelationparametersButtonGroup, 'numeric');
            app.minCorrelationEdit.ValueChangedFcn = createCallbackFcn(app, @minCorrelationEditValueChanged, true);
            app.minCorrelationEdit.Value = 0.7;
            app.similarityNeighEdit = uieditfield(app.CrosscorrelationparametersButtonGroup, 'numeric');
            app.similarityNeighEdit.ValueChangedFcn = createCallbackFcn(app, @similarityNeighEditValueChanged, true);
            app.similarityNeighEdit.Value = 25;
            app.MethodDropDownLabel = uilabel(app.CrosscorrelationparametersButtonGroup);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.CrosscorrelationparametersButtonGroup);
            app.MethodDropDown.Items = {'By min. # of particles', 'By interrogation area'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'By interrogation area';
            app.PTValgorithmLabel = uilabel(app.PTVsettings);
            app.PTValgorithmLabel.Text = ' PTV algorithm';
            app.PTValgorithmDropDown = uidropdown(app.PTVsettings);
            app.PTValgorithmDropDown.Items = {'Cross-correlation (cc)', 'Relaxation', 'Hybrid'};
            app.PTValgorithmDropDown.Value = 'Cross-correlation (cc)';
            app.particleDetectionPanel = uipanel(app.ptvPanel);
            app.particleDetectionPanel.Title = 'Particle Detection';
            app.text18 = uilabel(app.particleDetectionPanel);
            app.text18.Text = 'Correlation threshold';
            app.text19 = uilabel(app.particleDetectionPanel);
            app.text19.Text = 'Reprs. diameter [px]';
            app.text20 = uilabel(app.particleDetectionPanel);
            app.text20.Text = 'Intensity threshold';
            app.setSigmaAutoCheckBox = uicheckbox(app.particleDetectionPanel);
            app.setSigmaAutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @setSigmaAutoCheckBoxValueChanged, true);
            app.setSigmaAutoCheckBox.Text = 'Set diameter automatically';
            app.setSigmaAutoCheckBox.Value = true;
            app.corrThresholdEdit = uieditfield(app.particleDetectionPanel, 'numeric');
            app.corrThresholdEdit.ValueChangedFcn = createCallbackFcn(app, @corrThresholdEditValueChanged, true);
            app.corrThresholdEdit.Value = 0.4;
            app.intensityThresholdEdit = uieditfield(app.particleDetectionPanel, 'numeric');
            app.intensityThresholdEdit.ValueChangedFcn = createCallbackFcn(app, @intensityThresholdEditValueChanged, true);
            app.intensityThresholdEdit.Value = 70;
            app.sigmaEdit = uieditfield(app.particleDetectionPanel, 'numeric');
            app.sigmaEdit.ValueChangedFcn = createCallbackFcn(app, @sigmaEditValueChanged, true);
            app.sigmaEdit.Enable = 'off';
            app.sigmaEdit.Value = 8;
            app.ParticleDetectionAlgorithmDropDownLabel = uilabel(app.particleDetectionPanel);
            app.ParticleDetectionAlgorithmDropDownLabel.Text = 'Particle Detection Algorithm';
            app.ParticleDetectionAlgorithmDropDown = uidropdown(app.particleDetectionPanel);
            app.ParticleDetectionAlgorithmDropDown.Items = {'Gussian Mask', 'Dynamic Threshold'};
            app.ParticleDetectionAlgorithmDropDown.Value = 'Gussian Mask';
            app.abalysisdomainotions = uipanel(app.ImageAnalysisTab);
            app.abalysisdomainotions.Title = 'ANALYSIS DOMAIN OPTIONS';
            app.nDomainsText = uilabel(app.abalysisdomainotions);
            app.nDomainsText.Text = 'nr. of domains :';
            app.calibrationText = uilabel(app.abalysisdomainotions);
            app.calibrationText.Text = 'calibration [μm/px]:';
            app.GAdomainsText = uilabel(app.abalysisdomainotions);
            app.GAdomainsText.Text = {'nr. additional gen.'; 'analysis domains:'};
            app.nDomainsEdit = uieditfield(app.abalysisdomainotions, 'numeric');
            app.nDomainsEdit.Value = 1;
            app.calibrationEdit = uieditfield(app.abalysisdomainotions, 'numeric');
            app.calibrationEdit.Value = 0.65;
            app.GAdomainsEdit = uieditfield(app.abalysisdomainotions, 'numeric');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVE_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)CVE_GUI_test_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end